classdef Auraliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        InputsPanel                 matlab.ui.container.Panel
        SOFApath                    matlab.ui.control.EditField
        AUDIOpath                   matlab.ui.control.EditField
        HRTFSOFAButton              matlab.ui.control.StateButton
        AudioWAVButton              matlab.ui.control.StateButton
        OptionalInputsPanel         matlab.ui.container.Panel
        opt_IRpath                  matlab.ui.control.EditField
        HpTFWAVButton               matlab.ui.control.StateButton
        XButton                     matlab.ui.control.Button
        DIstancemLabel              matlab.ui.control.Label
        DistanceSlider              matlab.ui.control.Slider
        AirabsorptionCheckBox       matlab.ui.control.CheckBox
        StereoRenderButton          matlab.ui.control.StateButton
        PlayButton                  matlab.ui.control.StateButton
        PauseButton                 matlab.ui.control.StateButton
        InterpolationDropDownLabel  matlab.ui.control.Label
        InterpolationDropDown       matlab.ui.control.DropDown
        BufferSizeLabel             matlab.ui.control.Label
        BufferSize                  matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        AdvancedTab                 matlab.ui.container.Tab
        AirabsorptionpropertiesISO96131Panel  matlab.ui.container.Panel
        TemperatureCEditFieldLabel  matlab.ui.control.Label
        airTemperature              matlab.ui.control.NumericEditField
        HumidityLabel               matlab.ui.control.Label
        airHumidity                 matlab.ui.control.NumericEditField
        AtmosphericpressureratioCurrentStandadEditFieldLabel  matlab.ui.control.Label
        airPressure                 matlab.ui.control.NumericEditField
        CAMHeadTrackerButton        matlab.ui.control.Button
    end

    properties (Access = public)
        Obj_HRTF      % SOFA HRTF file
        posi          % posições de fonte 
        fs_SOFA       % sample rate SOFA
        fs_Audio      % sample rate Audio 
        Audio         % Audio file input
        deviceWriter  % dispositivo de reprodução
        dspHRIR_L     % HRIR esquerda para a posição selecionada
        dspHRIR_R     % HRIR esquerda para a posição selecionada
        NameAudio     % nome no Audio sem extensão
        Audiofolder   % folder path 
        opt_IR        % input IR opcional
        fs_opt_IR     % sample rate da IR opcional
        no_input      % numero de canais de entrada 
        flag_opt_IR   % avisa se ha input
        ROI           % região de interesse (posição da fonte)
        ROI_flag      % avisa se ROI foi inicializado ou não
        convAudio     % audio convoluido com resposta impulsiva
        flag_interp   % flag de erro de interpolação
        ROI_list      % lista com nome dos ROI
        headradius    % Raio da cabeça do sujeito 
        samplingGrid  % Posições SOFA em coordenadas pro SUpDEq
        DistNorm      % Amplitude normalization for distance variation
        startUP       % flag pra carregar arquivos default
        DistValue     % valor da distancia atribuida pelo slider
        FIR           % Alocated FIR filters 
        idx_FIR       % Index do filtro correspondente a posição atual 
        bkp_FIR       % save FIR when not in use, avoid ifs in the main loop
        MarkerSize    % Tamanho da bolinha do ROI
        air_abs       % coeficiente de absorção do ar
        N             % 1/2 do número de samples da HRIR
        flag_DistChanged   % flag pra indicar se distancia foi variada ou não
        flag_HeadTracker   % flag pra indicar se HT está ligado 
        udpr           % UDP connection to the head tracker
    end
        
    methods (Access = private)               
        function HRIR_filter_design(app, azim, elev, ch)
            %%%% Por interpolação
%             if any(strcmp(app.InterpolationDropDown.Value, {'VBAP', 'Bilinear'}))                
%                     % miinterolateHRTF possui pequenas correções na função interna do matlab
%                     interpolatedIR = miinterpolateHRTF(shiftdim(app.Obj_HRTF.Data.IR,1),...
%                                                      app.posi(:,[1,2]),[azim, elev],...
%                                                      'Algorithm', app.InterpolationDropDown.Value);   
%                     app.dspHRIR_L.(ch) = squeeze(interpolatedIR(:,1,:)).';
%                     app.dspHRIR_R.(ch) = squeeze(interpolatedIR(:,2,:)).'; 
%                     % Caso erro na interpolação vai sem interpolação
%                     if any(isnan(interpolatedIR(:)))
%                         app.flag_interp = 'Empty';                        
%                     end
            %%%% Pela posição mais próxima
%             else % any(strcmp(app.InterpolationDropDown.Value, 'Nearest')) ||...
                 %  any(strcmp(app.flag_interp, 'Empty'))
                    [azim,elev]=nav2sph(-azim,elev);
                    [posL, posR] = get_parallax_pos(app, azim, elev);
                    app.idx_FIR.(ch) = [posL, posR];
                    if app.flag_DistChanged
                        get_DistNorm(app,azim,elev)                    
                    end
                    if app.AirabsorptionCheckBox.Value  
                       apply_air_abs(app,posL,posR);                      
                    end
%                     if strcmp(app.flag_interp, {'VBAP', 'Bilinear'})
%                         % Reseta flag em interpolação tenha dado errado
%                         app.flag_interp = ''; % util quando bilinear disponível
%                     end
%             end
        end       
        
        function allevents(app, src, ~)
            HRIR_filter_design(app, src.Position(1), src.Position(2), src.Tag)
        end
        
        function apply_air_abs(app, idx_L, idx_R)           
            IR = [app.Obj_HRTF.Data.IR(:,idx_L,1),...
                  app.Obj_HRTF.Data.IR(:,idx_R,2)];
            LR = 20*log10(abs(fft(IR))) - app.air_abs;%*app.DistValue;
            LR = real(ifft(get_min_phase(LR(1:app.N, :),'log','nonsymmetric'), app.N));
%             size(LR)
            % atualizar valor dos no filtro FIR alocado e itd
            app.dspHRIR_L(:,idx_L) = circshift(LR(:,1),...
                                         app.Obj_HRTF.Data.Delay(idx_L, 1),1).';
            app.dspHRIR_R(:,idx_R) = circshift(LR(:,2),...
                                         app.Obj_HRTF.Data.Delay(idx_R, 2),1).';
        end
        
        function optional_IR_conv(app)
            wait = waitbar(0, 'Processing Impulse Responses', 'Name','Wait');
            release(app.Audio)                         
            release(app.deviceWriter)
            [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            convL = fast_conv(app, aud, app.opt_IR(:,1));
            waitbar(0.5)
            convR = fast_conv(app, aud, app.opt_IR(:,2));
            waitbar(0.9)
            y = [convL, convR];
            y = y./max(abs(y(:)))*0.97;
            app.convAudio = y;
            app.Audio = dsp.SignalSource(app.convAudio); 
            close(wait)                                      
        end
    
        function[posL, posR] = get_parallax_pos(app, azim, elev)         
            [samplingGridParL,samplingGridParR]=supdeq_parallax(app.samplingGrid,...
                                                                app.DistValue,...
                                                                app.headradius);           
            % Pegar o indice da posição parallaxada
            posL = dsearchn(samplingGridParL(:,1:2), [azim, 90-elev]);
            posR = dsearchn(samplingGridParR(:,1:2), [azim, 90-elev]);
        end
                       
        function markersize(app)
            app.MarkerSize = (app.headradius)/(app.DistValue) * 100;
        end
        
        function get_DistNorm(app, azim, elev)
            L_ear = app.Obj_HRTF.ReceiverPosition(1,:);
            R_ear = app.Obj_HRTF.ReceiverPosition(2,:);
            [tx, ty, tz] = sph2cart(azim, elev, app.DistValue);       
            Ldist = sqrt((L_ear(1) - tx)^2 + (L_ear(2) - ty)^2 + (L_ear(3) - tz)^2);
            Rdist = sqrt((R_ear(1) - tx)^2 + (R_ear(2) - ty)^2 + (R_ear(3) - tz)^2);
            % Calculate distance normalization
            app.DistNorm = [1/Ldist, 1/Rdist];
            app.MarkerSize = app.DistNorm(1)*10;
            app.flag_DistChanged = 0;
        end     
        
        function y = fast_conv(~,x1, x2)
            nfft = length(x1) + length(x2) - 1;
            y = real(ifft(fft(x1, nfft).*fft(x2, nfft)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center')
            set(app.UIAxes,'Color',[0.1,0.1,0.15]) % fundo preto
            % Desabilitar iteratividade do plot
            disableDefaultInteractivity(app.UIAxes); 
            app.UIAxes.Toolbar.Visible = 'off'; 
            set(app.UIAxes,'XColor',[0.5 0.5 0.5]); % tick label colors
            set(app.UIAxes,'YColor',[0.5 0.5 0.5]); % tick label colors
            set(app.UIAxes, 'FontSize', 10)
            app.flag_opt_IR = 0;
            app.ROI_flag = 1;
            app.flag_interp = '';
            app.flag_HeadTracker = 0;
            % Carregar defaulf SOFA
            app.startUP = 1;
            HRTFSOFAButtonValueChanged(app,app.startUP);
            AudioWAVButtonValueChanged(app,app.startUP);
        end

        % Value changed function: HRTFSOFAButton
        function HRTFSOFAButtonValueChanged(app, event)
            if app.startUP
                baseName = 'individuo_141.sofa';
               app.Obj_HRTF = SOFAload(baseName);
               app.SOFApath.Value = baseName; 
%                app.startUP = 0;
            else % LOAD SOFA
                [baseName, folder] = uigetfile([pwd, filesep, '*.sofa'], 'Load SOFA HRTF:');
                app.SOFApath.Value = baseName;  
                figure(app.UIFigure) %keep focus on app window
                app.Obj_HRTF = SOFAload(fullfile(folder, baseName));
            end
            %%% Properties
            if length(app.Obj_HRTF.Data.Delay) ~= length(app.Obj_HRTF.SourcePosition)
                [~, app.Obj_HRTF] = SOFAgetITD(app.Obj_HRTF,'samples');% save ITD in Obj.Data.Delay
            end
            app.posi = app.Obj_HRTF.SourcePosition;
             % Convert to supdeq parallax coordinates
            app.samplingGrid = app.posi;
            app.samplingGrid(:,2) = 90-app.posi(:,2);% Mod apenas para calculo
            app.headradius = app.Obj_HRTF.ReceiverPosition(1, 2); % raio da cabeça
            app.DistanceSlider.Value = app.posi(1,3);
            app.DistValue = app.DistanceSlider.Value;
            % convert for ploting
            [app.posi(:,1), app.posi(:,2)] = sph2nav(app.posi(:,1),app.posi(:,2));
            app.posi(:,1) = - app.posi(:,1);
            app.fs_SOFA = app.Obj_HRTF.Data.SamplingRate;
            app.Obj_HRTF.Data.IR = shiftdim(app.Obj_HRTF.Data.IR, 2);            
            app.dspHRIR_L = app.Obj_HRTF.Data.IR(:,:,1);
            app.dspHRIR_R = app.Obj_HRTF.Data.IR(:,:,2);
            % Verificar se absorção do ar foi ativada
            AirabsorptionCheckBoxValueChanged(app, 1)
            % Alocate FIR filter
            app.FIR{1} = dsp.FIRFilter('NumeratorSource','Input port');
            app.FIR{2} = dsp.FIRFilter('NumeratorSource','Input port');  
            app.bkp_FIR = app.FIR; % save copy in case we want to come back 
            % General flags
            if app.no_input >= 1 % Verifica se audio já foi inserido
                app.PlayButton.Enable = true;                   
                app.PauseButton.Enable = true;
                app.ROI_flag = 1;
            end  
            app.flag_DistChanged = 1;
        end

        % Value changed function: AudioWAVButton
        function AudioWAVButtonValueChanged(app, event)
            frameLength = app.BufferSize.Value; %make this a user defined option
            if app.startUP
                app.NameAudio = 'Voice Sabine Short_edited.wav';
                app.Audio = dsp.AudioFileReader( app.NameAudio, ...
                            'SamplesPerFrame', frameLength);    
                app.startUP = 0;
            else
                [NameAud, Audfolder] = uigetfile([pwd, filesep, '*.wav'], 'Load Audio File:');
                app.NameAudio = NameAud; %nomeie fora da função, caso o usuario feche a janela sem selecionar arquivo
                app.Audiofolder = Audfolder;
                figure(app.UIFigure) %keep focus on the app window
                app.Audio = dsp.AudioFileReader(fullfile(app.Audiofolder, app.NameAudio), ...
                            'SamplesPerFrame', frameLength);
            end           
            app.AUDIOpath.Value = app.NameAudio;   
            app.fs_Audio = app.Audio.SampleRate;      
            app.deviceWriter = audioDeviceWriter( ...
                                    "SampleRate", app.fs_Audio,...
                                    "BitDepth","32-bit float"); 
            app.no_input = size(app.Audio(), 2);           
            % General flags
            if isfield(app.Obj_HRTF, 'Data') % Verifica se HRTFs já foram inseridas
                app.PlayButton.Enable = true;                   
                app.PauseButton.Enable = true;
                app.ROI_flag = 1;
            end
      
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.PlayButton.Value = 0;
            app.PlayButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.HpTFWAVButton.Enable = false;
            app.StereoRenderButton.Enable = false;
            app.XButton.Enable = false;
            app.PauseButton.Value = 0;
            
            % only allow auralize if Sample Rate matchs
            if app.fs_Audio ~= app.fs_SOFA 
                warndlg(['TAXA DE AMOSTRAGEM: HRTFs estão em ', ...
                    num2str(app.fs_SOFA/1000) 'kHz, mas Audio está em ', ...
                    num2str(app.fs_Audio/1000) 'kHz'], 'Erro');
            else                 
                if app.ROI_flag % caso precise redesenhar
                   %%%% PLOT positions
                    hold(app.UIAxes, 'off');                    
                    scatter(app.UIAxes, app.posi(:,1), app.posi(:,2), 27,... 
                            'square', 'filled', 'MarkerFaceColor',[0.5, 0.47, 0.53]);                                          
                    hold(app.UIAxes, 'on');            
                    axis(app.UIAxes, 'tight');    
                    
                    % Fazer convolução IR 
                    if app.flag_opt_IR
                        if app.fs_opt_IR == app.fs_SOFA
                            if size(app.opt_IR, 2) < 2                               
                                app.opt_IR(:,2) = app.opt_IR(:,1);                                                                                                                             
                            end
                            % IR CONVOLUTION  
                            optional_IR_conv(app)
                        else 
                            warndlg(['TAXA DE AMOSTRAGEM: HRTFs estão em ', ...
                            num2str(app.fs_SOFA/1000) 'kHz, mas IR está em ', ...
                            num2str(app.fs_opt_IR/1000) 'kHz'], 'Erro');
                        end
                    end
                end        
                            
                %> Initialize filters
                azimm = -10;
                app.ROI_list = cell(app.no_input,1);
                for k = 1:app.no_input                      
                    roi_name = (['CH' num2str(k)]);
                    app.ROI_list{k} = roi_name;
  
                    %%% Desenhar ROI objeto %%%
                    if app.ROI_flag % evita reinicializar o ROI e outras coisas a cada play
                        markersize(app);
                        azimm = azimm+10; elevv = 0;
                        app.ROI.(roi_name) = drawpoint(app.UIAxes, 'Position',[azimm, elevv],...
                                                       'color', 'y', ...
                                                       'MarkerSize', app.MarkerSize);
                        app.ROI.(roi_name).Tag = roi_name; % Valor interno 
                        HRIR_filter_design(app, azimm, elevv, roi_name);  
                        addlistener(app.ROI.(roi_name), 'MovingROI', @app.allevents);
                        app.ROI.(roi_name).Label = roi_name; % Valor visível                    
                    end
                end
                
                app.ROI_flag = 0;   % Indica que já foi desenhado              
                
                
                % Play Obj_HRTFect setup
                release(app.Audio)
                release(app.deviceWriter)
                app.BufferSize.Value = 2^nextpow2(app.BufferSize.Value);
                app.Audio.SamplesPerFrame = app.BufferSize.Value;
                setup(app.deviceWriter,zeros(app.Audio.SamplesPerFrame, app.no_input))
                
                %%% PLAY AND PROCESS AUDIO %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                out_temp_L = zeros(length(app.Audio()),1);
                out_temp_R = zeros(length(app.Audio()),1);
                yaw = 0;
                pitch = 0;
                pos = app.posi(:,1:2);
                while ~isDone(app.Audio)   
                    pause(0); % permite a movimentação do cursor  
                    % Check pause button action
                    if app.PauseButton.Value      
                        break
                    end
                    
                    audioIn = app.Audio(); 
                    
                    % Apply HRTFs
                    if app.flag_HeadTracker
                        py_output = app.udpr();
                        if ~isempty(py_output)                          
                            data = str2num(convertCharsToStrings(char(py_output))); %#ok<*ST2NM>
                            yaw = data(1);
                            pitch = data(2);
                        end
                    end                    
                    for k = 1:app.no_input
                        name = app.ROI_list{k};                   
                        % Correct for Head Tracker position
                        if app.flag_HeadTracker
                            idx_posL = dsearchn(pos, ...
                                               (pos(app.idx_FIR.(name)(1)) - [yaw, pitch])); 
                            idx_posR = dsearchn(pos, ...
                                               (pos(app.idx_FIR.(name)(2)) - [yaw, pitch]));
%                             idx_posR = idx_posL;
                        else
                            idx_posL = app.idx_FIR.(name)(1);
                            idx_posR = app.idx_FIR.(name)(2);
                        end
                        
                        % HRTFs     
                        out_temp_L(:,k) = app.FIR{1}(audioIn(:,k), app.dspHRIR_L(:,idx_posL).'); % Left
                        if app.flag_opt_IR % Caso IR opcional inserida
                            out_temp_R(:,k) = app.FIR{2}(audioIn(:,k+app.no_input), app.dspHRIR_R(:,idx_posR).'); % Right
                            % Acima: o termo (+app.no_input) foi adicionado
                            % pq cada canal do audioIn foi convoluido com
                            % os dois canais da RI inserida
                        else        
                            out_temp_R(:,k) = app.FIR{2}(audioIn(:,k),  app.dspHRIR_R(:,idx_posR).'); % Right                        
                        end
                    end    
                    y = [mean(out_temp_L,2), mean(out_temp_R,2)] .*app.DistNorm;
                    app.deviceWriter(y);   
                end                      
                release(app.Audio)                         
                release(app.deviceWriter)  
                app.PlayButton.Enable = true;
                app.HRTFSOFAButton.Enable = true;
                app.AudioWAVButton.Enable = true;
                app.HpTFWAVButton.Enable = true;
                app.StereoRenderButton.Enable = true; 
                if app.flag_opt_IR
                   app.XButton.Enable = true;
                end               
            end           
        end

        % Value changed function: HpTFWAVButton
        function HpTFWAVButtonValueChanged(app, event)
            [IR_name, folder] = uigetfile([pwd, filesep, '*.wav'], 'Load Impulse Response:');
            figure(app.UIFigure) %keep focus on the app window
            [temp_ir, app.fs_opt_IR] = audioread(fullfile(folder, IR_name));
            % Suporte para no máximo dois canais
            if size(temp_ir, 2) > 2
                 warndlg('Número de canais não suportado, máximo 2 canais', 'Erro');
            else
                app.opt_IR = (temp_ir./max(abs(temp_ir(:))))*0.97;
                app.opt_IRpath.Value = IR_name;
                app.flag_opt_IR = 1;
                app.ROI_flag = 1;                
                app.XButton.Enable = true;              
            end          
        end

        % Value changed function: StereoRenderButton
        function StereoRenderButtonValueChanged(app, event)
            release(app.Audio)                         
            release(app.deviceWriter)   
            app.StereoRenderButton.Enable = false;
            app.PlayButton.Enable = false;
            app.PauseButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.HpTFWAVButton.Enable = false;
            app.XButton.Enable = false;
            
            wait = waitbar(0, 'Processing output...', 'Name','Rendering:');           
            if ~app.flag_opt_IR % Load audio again
                [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            else
                optional_IR_conv(app) % make sure IR is convoluted
            end
            
            for k = 1:app.no_input    
                roi_name = (['CH' num2str(k)]);
                waitbar(k/app.no_input, wait)
                % Convolução saída        
                if app.flag_opt_IR % Caso IR opcional inserida                   
                    nfft = length(app.convAudio(:,k)) + length(app.dspHRIR_L.(roi_name)) - 1;
                    out_render_L(:,k) = real(ifft(fft(app.convAudio(:,k), nfft).* fft(app.dspHRIR_L.(roi_name).', nfft), nfft)); % Left
                    out_render_R(:,k) = real(ifft(fft(app.convAudio(:,k+app.no_input), nfft).* fft(app.dspHRIR_R.(roi_name).', nfft), nfft)); % Right
                else
                    nfft = length(aud(:,k)) + length(app.dspHRIR_L.(roi_name)) - 1;
                    out_render_L(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_L.(roi_name).',nfft),nfft); % Left
                    out_render_R(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_R.(roi_name).',nfft),nfft); % Right
                end
                waitbar((k/app.no_input + k/(app.no_input*2)), wait)
            end 
                      
            y = [mean(out_render_L,2) mean(out_render_R,2)];  
            y = y./max(abs(y(:)))*0.97;
            % Ask for save path and name        
            [~, baseFileNameNoExt, ~] = fileparts([app.Audiofolder  app.NameAudio ]);
            [file,path,~] = uiputfile([baseFileNameNoExt, '_EAC.wav']);
            figure(app.UIFigure) %keep focus on the app window
            audiowrite([path file], y, app.fs_Audio)
            waitbar(1, wait); pause(1); close(wait)
            
            app.StereoRenderButton.Enable = true;
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.HpTFWAVButton.Enable = true;   
            app.XButton.Enable = true;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.fs_opt_IR = [];
            app.opt_IR = [];
            app.opt_IRpath.Value = '';
            app.flag_opt_IR = 0;
            app.ROI_flag = 1;
            % Remover convolução
            release(app.Audio)                         
            release(app.deviceWriter)
            [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            app.Audio = dsp.SignalSource(aud); 
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            app.DistValue = event.Value;
            app.flag_DistChanged = 1;
            if ~app.ROI_flag
                for k=1:app.no_input              
                    HRIR_filter_design(app, app.ROI.(app.ROI_list{k}).Position(1),...
                                      app.ROI.(app.ROI_list{k}).Position(2),...
                                      app.ROI.(app.ROI_list{k}).Tag)
                    % Marker size
                    app.ROI.(app.ROI_list{k}).MarkerSize = app.MarkerSize;               
                end               
            end
        end

        % Value changed function: AirabsorptionCheckBox
        function AirabsorptionCheckBoxValueChanged(app, event)
            if app.AirabsorptionCheckBox.Value
                app.N = size(app.Obj_HRTF.Data.IR,1);
                freq = linspace(0, app.fs_SOFA-app.fs_SOFA/app.N, app.N);
                app.air_abs = 0;
                for k = 1:length(freq)
                    [~, app.air_abs(k,1), ~, ~] = air_absorption(freq(k),...
                                                            app.airTemperature,...
                                                            app.airHumidity,...
                                                            app.airPressure);                    
                end
                app.air_abs(:,2) = app.air_abs(:,1);               
                if ~app.ROI_flag
                    for k=1:app.no_input              
                        HRIR_filter_design(app, app.ROI.(app.ROI_list{k}).Position(1),...
                                          app.ROI.(app.ROI_list{k}).Position(2),...
                                          app.ROI.(app.ROI_list{k}).Tag)             
                    end               
                end
            else % reset it all 
                app.FIR = app.bkp_FIR;
            end
        end

        % Button pushed function: CAMHeadTrackerButton
        function CAMHeadTrackerButtonPushed(app, event)
            try
                open("HeadTracker.exe")
            catch
                warndlg(['HeadTracker.exe não foi encontrado, '...
                         'adicione ao path do Matlab'], 'Erro');
            end
            IP = '127.0.0.1';
            PORT = 50050;
            app.udpr = dsp.UDPReceiver('RemoteIPAddress', IP,...
                                       'LocalIPPort', PORT, ...
                                       'ReceiveBufferSize', 8);
            app.flag_HeadTracker = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.InputsPanel = uipanel(app.MainTab);
            app.InputsPanel.Title = 'Inputs';
            app.SOFApath = uieditfield(app.InputsPanel, 'text');
            app.SOFApath.Editable = 'off';
            app.AUDIOpath = uieditfield(app.InputsPanel, 'text');
            app.AUDIOpath.Editable = 'off';
            app.HRTFSOFAButton = uibutton(app.InputsPanel, 'state');
            app.HRTFSOFAButton.ValueChangedFcn = createCallbackFcn(app, @HRTFSOFAButtonValueChanged, true);
            app.HRTFSOFAButton.Text = 'HRTF (.SOFA)';
            app.AudioWAVButton = uibutton(app.InputsPanel, 'state');
            app.AudioWAVButton.ValueChangedFcn = createCallbackFcn(app, @AudioWAVButtonValueChanged, true);
            app.AudioWAVButton.Text = 'Audio (.WAV)';
            app.OptionalInputsPanel = uipanel(app.MainTab);
            app.OptionalInputsPanel.Title = 'Optional Inputs';
            app.opt_IRpath = uieditfield(app.OptionalInputsPanel, 'text');
            app.opt_IRpath.Editable = 'off';
            app.HpTFWAVButton = uibutton(app.OptionalInputsPanel, 'state');
            app.HpTFWAVButton.ValueChangedFcn = createCallbackFcn(app, @HpTFWAVButtonValueChanged, true);
            app.HpTFWAVButton.Text = 'HpTF (.WAV)';
            app.XButton = uibutton(app.OptionalInputsPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X';
            app.DIstancemLabel = uilabel(app.MainTab);
            app.DIstancemLabel.Text = {'DIstance'; '(m)'};
            app.DistanceSlider = uislider(app.MainTab);
            app.DistanceSlider.Limits = [0.25 5];
            app.DistanceSlider.MajorTicks = [0.25 1.25 2.25 3.25 4.25];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.MinorTicks = [];
            app.DistanceSlider.Value = 2.5;
            app.AirabsorptionCheckBox = uicheckbox(app.MainTab);
            app.AirabsorptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AirabsorptionCheckBoxValueChanged, true);
            app.AirabsorptionCheckBox.Text = 'Air absorption';
            app.AirabsorptionCheckBox.Value = true;
            app.StereoRenderButton = uibutton(app.MainTab, 'state');
            app.StereoRenderButton.ValueChangedFcn = createCallbackFcn(app, @StereoRenderButtonValueChanged, true);
            app.StereoRenderButton.Enable = 'off';
            app.StereoRenderButton.Text = 'Stereo Render';
            app.PlayButton = uibutton(app.MainTab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = 'playbuttom.png';
            app.PlayButton.Text = {''; ''};
            app.PauseButton = uibutton(app.MainTab, 'state');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Icon = 'stopbuttom.png';
            app.PauseButton.Text = '';
            app.InterpolationDropDownLabel = uilabel(app.MainTab);
            app.InterpolationDropDownLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.MainTab);
            app.InterpolationDropDown.Items = {'Nearest'};
            app.InterpolationDropDown.Value = 'Nearest';
            app.BufferSizeLabel = uilabel(app.MainTab);
            app.BufferSizeLabel.Text = 'Buffer Size';
            app.BufferSize = uieditfield(app.MainTab, 'numeric');
            app.BufferSize.Value = 512;
            app.UIAxes = uiaxes(app.MainTab);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AirabsorptionpropertiesISO96131Panel = uipanel(app.AdvancedTab);
            app.AirabsorptionpropertiesISO96131Panel.Title = 'Air absorption properties (ISO 9613-1)';
            app.TemperatureCEditFieldLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C°)';
            app.airTemperature = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airTemperature.Value = 20;
            app.HumidityLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.HumidityLabel.Text = 'Humidity (%) ';
            app.airHumidity = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airHumidity.Value = 80;
            app.AtmosphericpressureratioCurrentStandadEditFieldLabel = uilabel(app.AirabsorptionpropertiesISO96131Panel);
            app.AtmosphericpressureratioCurrentStandadEditFieldLabel.Text = {'Atmospheric pressure ratio'; '(Current / Standad) '};
            app.airPressure = uieditfield(app.AirabsorptionpropertiesISO96131Panel, 'numeric');
            app.airPressure.Value = 1;
            app.CAMHeadTrackerButton = uibutton(app.AdvancedTab, 'push');
            app.CAMHeadTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @CAMHeadTrackerButtonPushed, true);
            app.CAMHeadTrackerButton.Text = 'CAM Head Tracker';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Auraliza

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end